classdef Acoustic_Simulator_Updated < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        refreshButton              matlab.ui.control.Button
        CoordinateDisplayTextArea  matlab.ui.control.TextArea
        InformationDisplayTextAreaLabel  matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        AcousticTriangulationSimulatorLabel  matlab.ui.control.Label
        DetermineSourceButton      matlab.ui.control.Button
        UIAxes1                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        % Default grid size if user does not define own locations of microphones before determining coordinates
        mic_positions = [0, 0;      % Position of mic1
                 0, 0.5;    % Position of mic2
                 0.8, 0;    % Position of mic3
                 0.8, 0.5]; % Position of mic4
    end
    
    properties (Access = private)
        gridLengthX = 0.8;
    end
    
    properties (Access = public)
        gridLengthY = 0.5;
    end

    properties (Access=public)
        noiselevel = 0.4;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonValueChanged(app, event)
            value = app.InitializeGridButton.Value;
            
            
        end

        % Button pushed function: DetermineSourceButton
        function DetermineSourceButtonPushed(app, event)

% Define the path to the Python script file
%{
python_script_file = 'PiRecord.py';

% Use the system function to run your Python script
system(['python ', python_script_file]);
%}
clear; clc; close all;

sampling_rate = 48000; % Set a sampling rate in Hertz
prop_speed = 343; % Speed of sound value in m/s

SyncSignal =  [0.3 0.3];  
mic_positions = [0, 0;      % Position of mic1
                 0, 0.5;    % Position of mic2
                 0.8, 0;    % Position of mic3
                 0.8, 0.5]; % Position of mic4

Lookup = Create_LTable(100,mic_positions(1,:),mic_positions(2,:),mic_positions(3,:),mic_positions(4,:));     % Generate Look up table for TODA WRT mic 1


[sig3, sig4] = splitStereoToMono('pi1stereo.wav');
[sig1, sig2] = splitStereoToMono('pi2stereo.wav');
[reference_sig,Fs_chirp] = audioread("chirp.wav");


%Find ideal TOA of calibration signal
t0_mic1 = sqrt((mic_positions(1,1)-SyncSignal(1))^2+(mic_positions(1,2)-SyncSignal(2))^2)/prop_speed; 
t0_mic2 = sqrt((mic_positions(2,1)-SyncSignal(1))^2+(mic_positions(2,2)-SyncSignal(2))^2)/prop_speed;
t0_mic3 = sqrt((mic_positions(3,1)-SyncSignal(1))^2+(mic_positions(3,2)-SyncSignal(2))^2)/prop_speed;
t0_mic4 = sqrt((mic_positions(4,1)-SyncSignal(1))^2+(mic_positions(4,2)-SyncSignal(2))^2)/prop_speed;


sampling_rate = 48000;
toa_cal = zeros(1, 4);
toa_snd = zeros(1, 4);
signals = {sig1, sig2, sig3, sig4};

for i = 1:4
    y = signals{i};

    % First correlation
    [Correlation_arr, lag_arr] = xcorr(y, reference_sig);
    [~, index_of_lag] = max(abs(Correlation_arr));
    peak1 = lag_arr(index_of_lag);

    % Flatten y
    flattern = zeros(1, 3 * sampling_rate);
    flat = length(flattern);
    y(peak1:peak1+flat-1) = flattern;

    % Second correlation
    [Correlation_arr, lag_arr] = xcorr(y, reference_sig);
    [~, index_of_lag] = max(abs(Correlation_arr));
    peak2 = lag_arr(index_of_lag);

    % Calculate TOA
    peaks = [peak1, peak2];
    toa_cal(i) = min(peaks) / sampling_rate;
    toa_snd(i) = max(peaks) / sampling_rate;
end

%{

%%
%Find TOAs of calibration signal and signal of interest
[cal1,snd1] = findTOA(sig1 , reference_sig);
[cal2,snd2] = findTOA(sig2 , reference_sig);
[cal3,snd3] = findTOA(sig3 , reference_sig);
[cal4,snd4] = findTOA(sig4 , reference_sig);

%}

%Synchronise TOAs based on Calibration TOA
RTOA1 = toa_snd(1)-toa_cal(1)+t0_mic1;
RTOA2 = toa_snd(2)-toa_cal(2)+t0_mic2;
RTOA3 = toa_snd(3)-toa_cal(3)+t0_mic3;
RTOA4 = toa_snd(4)-toa_cal(4)+t0_mic4;

%Form TDOAs
Time_diff21 = RTOA2 - RTOA1 ;
Time_diff31 = RTOA3 - RTOA1 ;
Time_diff41 = RTOA4 - RTOA1 ;
Time_diff32 = RTOA3 - RTOA2 ;
Time_diff42 = RTOA4 - RTOA2 ;
Time_diff43 = RTOA4 - RTOA3 ;

[Estimation1, Estimation2, Estimation3, Estimation4]   = find_position(Lookup,Time_diff21,Time_diff31,Time_diff41,Time_diff32,Time_diff42,Time_diff43);

estimations = [];

% Compare position estimations
if (Estimation1(1) ~= 0 && Estimation1(2) ~= 0 && Estimation1(1) ~= mic_positions(4,1) && Estimation1(2) ~= mic_positions(4,2))
    estimations(:, end+1) = Estimation1;
end

if (Estimation2(1) ~= mic_positions(1,1) && Estimation2(2) ~= mic_positions(1,1) && Estimation2(1) ~= mic_positions(4,1) && Estimation2(2) ~= mic_positions(4,2))
    estimations(:, end+1) = Estimation2';
end

if (Estimation3(1) ~= 0 && Estimation3(2) ~= 0 && Estimation3(1) ~= mic_positions(4,1) && Estimation3(2) ~= mic_positions(4,2))
    estimations(:, end+1) = Estimation3';
end

if (Estimation4(1) ~= 0 && Estimation4(2) ~= 0 && Estimation4(1) ~= mic_positions(4,1) && Estimation4(2) ~= mic_positions(4,2));
    estimations(:, end+1) = Estimation4';
end

estimations( :, all(~estimations,1) ) = [];
if isempty(estimations)
    disp("Estimation error - inaccurate triangulated position")
    Triangulation_estimate = Estimation1;
else
    Triangulation_estimate(1) = mean(estimations(1,:));   
    Triangulation_estimate(2) = mean(estimations(2,:));

end

displayText="";
fprintf("Lookup table estimated position:  X = %.3f  Y = %.3f\n",Triangulation_estimate(1),Triangulation_estimate(2));
displayText = append(displayText, sprintf("Triangulated Source Position Estimation:\n X: %.4f\n Y: %.4f\n", Triangulation_estimate(1), Triangulation_estimate(2)));
% Open the CSV file for appending
fid = fopen('results1.csv', 'a');

% Write the results to the CSV file
fprintf(fid, '%f,%f,%f,%f,%f\n', Triangulation_estimate(1), Triangulation_estimate(2));

% Close the CSV file
fclose(fid);


% Update the CoordinateDisplayTextArea with the concatenated text
app.CoordinateDisplayTextArea.Value = displayText;


          % Define the grid intervals
x_min = 0;
x_max = 0.8;
y_min = 0;
y_max = 0.5;
x_intervals = 0.1;
y_intervals = 0.1;

            % Create the x and y values for the grid
x_values = x_min:x_intervals:x_max;
y_values = y_min:y_intervals:y_max;

% Plotting of the grid lines
%hold(app.UIAxes1, 'on');
grid(app.UIAxes1, 'on');
xticks(app.UIAxes1, x_values);
yticks(app.UIAxes1, y_values);

microphone_colors = 'k';  % Change to black colour for mics
microphone_radius = 1e-2;

for i = 1:size(app.mic_positions, 1)
    x = app.mic_positions(i, 1);
    y = app.mic_positions(i, 2);
    color = microphone_colors;
    
    % Draw circle
    t = linspace(0, 2*pi, 100);
    x_circle = microphone_radius * cos(t) + x;
    y_circle = microphone_radius * sin(t) + y;
    plot(app.UIAxes1, x_circle, y_circle, 'Color', color, 'LineWidth', 2);
end

% Plot a circle at the estimated source position
estimated_position_color = 'r';  % Change to red colour for mics
estimated_position_radius = 1e-2;


% Draw circle at estimated source position
t = linspace(0, 2*pi, 100);
x_circle = estimated_position_radius * cos(t) + Triangulation_estimate(1);
y_circle = estimated_position_radius * sin(t) + Triangulation_estimate(2);
plot(app.UIAxes1, x_circle, y_circle, 'Color', estimated_position_color, 'LineWidth', 2);


% Add a red crosshair inside the source position circle
crosshair_length = 3 * estimated_position_radius;
x_crosshair = [Triangulation_estimate(1) - crosshair_length / 2, Triangulation_estimate(1) + crosshair_length / 2];
y_crosshair = [Triangulation_estimate(2), Triangulation_estimate(2)];
plot(app.UIAxes1, x_crosshair, y_crosshair, 'Color', 'bl', 'LineWidth', 1);
x_crosshair = [Triangulation_estimate(1), Triangulation_estimate(1)];
y_crosshair = [Triangulation_estimate(2) - crosshair_length / 2, Triangulation_estimate(2) + crosshair_length / 2];
plot(app.UIAxes1, x_crosshair, y_crosshair, 'Color', 'bl', 'LineWidth', 1);


hold(app.UIAxes1, 'off');


function [LTable] = Create_LTable(table_resolution,mic1, mic2, mic3, mic4)

    Incrementor = 1;

  % Setting the x and y table limits based on the microphone positions
    x_min = min([mic1(1), mic2(1), mic3(1), mic4(1)]);
    x_max = max([mic1(1), mic2(1), mic3(1), mic4(1)]);
    y_min = min([mic1(2), mic2(2), mic3(2), mic4(2)]);
    y_max = max([mic1(2), mic2(2), mic3(2), mic4(2)]);
    
    % Calculation of the number of points for the table - based on the user defined table resolution
    num_points_x = round((x_max - x_min) * table_resolution);
    num_points_y = round((y_max - y_min) * table_resolution);
    num_points = num_points_x * num_points_y;
    
    % Initialize the lookup table
    LTable = zeros(num_points, 8);
    
    for i = 1:num_points_x
        for j = 1:num_points_y
            % Calculate the x and y values for this point
            x = x_min + (i - 1) / table_resolution;
            y = y_min + (j - 1) / table_resolution;
            
            % Calculate the time differences for each microphone
            TOA1 = sqrt((mic1(1) - x)^2 + (mic1(2) - y)^2) / 343;
            TOA2= sqrt((mic2(1) - x)^2 + (mic2(2) - y)^2) / 343;
            TOA3 = sqrt((mic3(1) - x)^2 + (mic3(2) - y)^2) / 343;
            TOA4 = sqrt((mic4(1) - x)^2 + (mic4(2) - y)^2) / 343;
            
            % Calculate the time differences between microphones
            TDoA21 = TOA2 - TOA1;
            TDoA31 = TOA3 - TOA1;
            TDoA41 = TOA4 - TOA1;
            TDoA32 = TOA3 - TOA2;
            TDoA42 = TOA4 - TOA2;
            TDoA43 = TOA4 - TOA3;
            
            % Store the values in the lookup table
            LTable(Incrementor, 1) = TDoA21;
            LTable(Incrementor, 2) = TDoA31;
            LTable(Incrementor, 3) = TDoA41;
            LTable(Incrementor, 4) = TDoA32;
            LTable(Incrementor, 5) = TDoA42;
            LTable(Incrementor, 6) = TDoA43;
            LTable(Incrementor, 7) = x;
            LTable(Incrementor, 8) = y;
            
            % Increment the counter
            Incrementor = Incrementor + 1;
        end
    end
end


function [Estimation1, Estimation2, Estimation3, Estimation4] = find_position(LTable,t21, t31, t41, t32, t42, t43)

    td1 = LTable(:, 4:6);
    td2 = [LTable(:, 2), LTable(:, 3), LTable(:, 6)];
    td3 = [LTable(:, 1), LTable(:, 3), LTable(:, 5)];
    td4 = [LTable(:, 1), LTable(:, 2), LTable(:, 4)];

    % Created variables for the individual time differences
    td_N1_1 = t32;
    td_N1_2 = t42;
    td_N1_3 = t43;

    td_N2_1 = t31;
    td_N2_2 = t41;
    td_N2_3 = t43;

    td_N3_1 = t21;
    td_N3_2 = t41;
    td_N3_3 = t42;

    td_N4_1 = t21;
    td_N4_2 = t31;
    td_N4_3 = t32;


% Calculated the time differences using bsxfun function
diff1 = bsxfun(@minus, [td1(:, 1), td1(:, 2), td1(:, 3)], [td_N1_1, td_N1_2, td_N1_3]);
diff2 = bsxfun(@minus, [td2(:, 1), td2(:, 2), td2(:, 3)], [td_N2_1, td_N2_2, td_N2_3]);
diff3 = bsxfun(@minus, [td3(:, 1), td3(:, 2), td3(:, 3)], [td_N3_1, td_N3_2, td_N3_3]);
diff4 = bsxfun(@minus, [td4(:, 1), td4(:, 2), td4(:, 3)], [td_N4_1, td_N4_2, td_N4_3]);


    [~, IN1] = min(sum(abs(diff1) .* 2, 2));
    [~, IN2] = min(sum(abs(diff2) .* 2, 2));
    [~, IN3] = min(sum(abs(diff3) .* 2, 2));
    [~, IN4] = min(sum(abs(diff4) .* 2, 2));

  
Estimation1 = LTable(IN1, [7, 8]);
Estimation2 = LTable(IN2, [7, 8]);
Estimation3 = LTable(IN3, [7, 8]);
Estimation4 = LTable(IN4, [7, 8]);

end


function [monoLeft, monoRight] = splitStereoToMono(stereoWavFile)
% Read the stereo .wav audio file
[stereoWav, ~] = audioread(stereoWavFile);

% Split the stereo signal into its two mono elements and return
monoLeft = stereoWav(:, 1);
monoRight = stereoWav(:, 2);
end

        end

        % Callback function
        function Button3Pushed(app, event)
             app.CoordinateDisplayTextArea.Value = num2str(231);
             pause(1);
        end

        % Callback function
        function InitializeGridButtonPushed(app, event)
               %Update
        % Get the value from the Numeric Edit Field
        mic1_x = app.XvalueEditField.Value;
        mic1_y = app.YvalueEditField.Value;
        mic2_x = app.XvalueEditField_2.Value;
        mic2_y = app.YvalueEditField_2.Value;
        mic3_x = app.XvalueEditField_3.Value;
        mic3_y = app.YvalueEditField_3.Value;
        mic4_x = app.XvalueEditField_4.Value;
        mic4_y = app.YvalueEditField_4.Value;
        
        app.mic_positions = [mic1_x , mic1_y;      % Position of mic1
                 mic2_x, mic2_y ;    % Position of mic2
                 mic3_x , mic3_y ;    % Position of mic3
                 mic4_x , mic4_y ]; % Position of mic4

        app.gridLengthX = mic4_x;
        app.gridLengthY = mic4_y;

        app.CoordinateDisplayTextArea.Value = "Grid initialized - ready to determine source";
        pause(1);
        end

        % Button pushed function: refreshButton
        function refreshButtonPushed(app, event)
           % Clear the grid by removing plotted objects
    cla(app.UIAxes1);

          % Redraw the grid lines (if needed)
    x_min = 0;
    x_max = 1; % Updated x_max to 0.8
    y_min = 0;
    y_max = 1; % Updated y_max to 0.5
    x_intervals = 0.1;
    y_intervals = 0.1;
    x_values = x_min:x_intervals:x_max;
    y_values = y_min:y_intervals:y_max;

    % Plotting of the grid lines
    hold(app.UIAxes1, 'on');
    grid(app.UIAxes1, 'on');
    xticks(app.UIAxes1, x_values);
    yticks(app.UIAxes1, y_values);

    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes1 = uiaxes(app.GridLayout);
            title(app.UIAxes1, 'Simulated Grid')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.DetermineSourceButton = uibutton(app.GridLayout, 'push');
            app.DetermineSourceButton.ButtonPushedFcn = createCallbackFcn(app, @DetermineSourceButtonPushed, true);
            app.DetermineSourceButton.Text = 'Determine Source ';
            app.AcousticTriangulationSimulatorLabel = uilabel(app.GridLayout);
            app.AcousticTriangulationSimulatorLabel.Text = 'Acoustic Triangulation Simulator';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = {'Project by:'; 'Joachim Gengan - GNGJOA003'; 'Kudzi Kadzimu - KDZKUD001'; 'Maisha Magavha - MGVMAI001'};
            app.InformationDisplayTextAreaLabel = uilabel(app.GridLayout);
            app.InformationDisplayTextAreaLabel.Text = 'Information Display';
            app.CoordinateDisplayTextArea = uitextarea(app.GridLayout);
            app.CoordinateDisplayTextArea.Value = {'Please initialize grid'};
            app.refreshButton = uibutton(app.GridLayout, 'push');
            app.refreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshButtonPushed, true);
            app.refreshButton.Text = 'refresh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Acoustic_Simulator_Updated

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end